classdef GUIMain_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1     matlab.ui.Figure
        uipanel8    matlab.ui.container.Panel
        text68      matlab.ui.control.Label
        text69      matlab.ui.control.Label
        uipanel4    matlab.ui.container.Panel
        szFun       matlab.ui.control.DropDown
        text32      matlab.ui.control.Label
        szBC        matlab.ui.control.DropDown
        text34      matlab.ui.control.Label
        text33      matlab.ui.control.Label
        szMode      matlab.ui.control.DropDown
        text35      matlab.ui.control.Label
        nMode_cs    matlab.ui.control.EditField
        text31      matlab.ui.control.Label
        uipanel3    matlab.ui.container.Panel
        text36      matlab.ui.control.Label
        text37      matlab.ui.control.Label
        text38      matlab.ui.control.Label
        dem         matlab.ui.control.EditField
        dvm         matlab.ui.control.EditField
        text39      matlab.ui.control.Label
        text40      matlab.ui.control.Label
        text41      matlab.ui.control.Label
        a_inner     matlab.ui.control.EditField
        b_middle    matlab.ui.control.EditField
        text42      matlab.ui.control.Label
        c_outer     matlab.ui.control.EditField
        Run         matlab.ui.control.Button
        Save        matlab.ui.control.Button
        Initialize  matlab.ui.control.Button
        uipanel2    matlab.ui.container.Panel
        text17      matlab.ui.control.Label
        text18      matlab.ui.control.Label
        text19      matlab.ui.control.Label
        text20      matlab.ui.control.Label
        fa          matlab.ui.control.EditField
        df          matlab.ui.control.EditField
        fb          matlab.ui.control.EditField
        text21      matlab.ui.control.Label
        text22      matlab.ui.control.Label
        text23      matlab.ui.control.Label
        text24      matlab.ui.control.Label
        cpa         matlab.ui.control.EditField
        dcp         matlab.ui.control.EditField
        cpb         matlab.ui.control.EditField
        text25      matlab.ui.control.Label
        text26      matlab.ui.control.Label
        text27      matlab.ui.control.Label
        text28      matlab.ui.control.Label
        kia         matlab.ui.control.EditField
        dki         matlab.ui.control.EditField
        kib         matlab.ui.control.EditField
        text30      matlab.ui.control.Label
        err         matlab.ui.control.EditField
        text29      matlab.ui.control.Label
        kur         matlab.ui.control.EditField
        text65      matlab.ui.control.Label
        uipanel1    matlab.ui.container.Panel
        text4       matlab.ui.control.Label
        text6       matlab.ui.control.Label
        text7       matlab.ui.control.Label
        text5       matlab.ui.control.Label
        text8       matlab.ui.control.Label
        rowem       matlab.ui.control.EditField
        Eem0        matlab.ui.control.EditField
        ytaem       matlab.ui.control.EditField
        sigmaem     matlab.ui.control.EditField
        text9       matlab.ui.control.Label
        text10      matlab.ui.control.Label
        text11      matlab.ui.control.Label
        text12      matlab.ui.control.Label
        text13      matlab.ui.control.Label
        rowvm       matlab.ui.control.EditField
        Evm0        matlab.ui.control.EditField
        ytavm       matlab.ui.control.EditField
        sigmavm     matlab.ui.control.EditField
        text1       matlab.ui.control.Label
        text2       matlab.ui.control.Label
        text3       matlab.ui.control.Label
        row1        matlab.ui.control.EditField
        c1          matlab.ui.control.EditField
        text14      matlab.ui.control.Label
        text15      matlab.ui.control.Label
        text16      matlab.ui.control.Label
        row2        matlab.ui.control.EditField
        c2          matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUIMain_OpeningFcn(app, varargin)
            % --- Executes just before GUIMain is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   unrecognized PropertyName/PropertyValue pairs from the
            %            command line (see VARARGIN)
            
            % Choose default command line output for GUIMain
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: Initialize
        function Initialize_Callback(app, event)
            % --- Executes on button press in Initialize.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Initialize (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            load('Initializer.mat');
            set(findobj('Tag','rowem'),'String',rowem); set(findobj('Tag','Eem0'),'String',Eem0);
            set(findobj('Tag','ytaem'),'String',ytaem); set(findobj('Tag','sigmaem'),'String',sigmaem);
            set(findobj('Tag','rowvm'),'String',rowvm); set(findobj('Tag','Evm0'),'String',Evm0);
            set(findobj('Tag','ytavm'),'String',ytavm); set(findobj('Tag','sigmavm'),'String',sigmavm);
            set(findobj('Tag','row1'),'String',row1); set(findobj('Tag','c1'),'String',c1);
            set(findobj('Tag','row2'),'String',row2);  set(findobj('Tag','c2'),'String',c2);
            
            set(findobj('Tag','dem'),'String',dem); set(findobj('Tag','dvm'),'String',dvm);
            set(findobj('Tag','a_inner'),'String',a_inner); set(findobj('Tag','b_middle'),'String',b_middle);
            set(findobj('Tag','c_outer'),'String',c_outer);
            set(findobj('Tag','fa'),'String',fa); set(findobj('Tag','df'),'String',df);
            set(findobj('Tag','fb'),'String',fb);
            set(findobj('Tag','cpa'),'String',cpa); set(findobj('Tag','dcp'),'String',dcp);
            set(findobj('Tag','cpb'),'String',cpb);
            set(findobj('Tag','kia'),'String',kia); set(findobj('Tag','dki'),'String',dki);
            set(findobj('Tag','kib'),'String',kib);
            set(findobj('Tag','kur'),'String',kur); set(findobj('Tag','err'),'String',err);
            
            switch szFun
                case 'PlanarPlate'
                    val = 1; set(findobj('Tag','szFun'),'value',val);
                case 'CylindricalShell'
                    val = 2; set(findobj('Tag','szFun'),'value',val);
            end
            switch szBC
                case 'Va-So-Va'
                    val = 1; set(findobj('Tag','szBC'),'value',val);
                case 'Va-So-Fl'
                    val = 2; set(findobj('Tag','szBC'),'value',val);
                case 'Fl-So-Fl'
                    val = 3; set(findobj('Tag','szBC'),'value',val);
                case 'Va-So-So-Va'
                    val = 4; set(findobj('Tag','szBC'),'value',val);
                case 'Va-So-So-Fl'
                    val = 5; set(findobj('Tag','szBC'),'value',val);
                case 'Fl-So-So-Fl'
                    val = 6; set(findobj('Tag','szBC'),'value',val);
            end
            switch szMode
                case 'S/L'
                    val = 1; set(findobj('Tag','szMode'),'value',val);
                case 'A/T'
                    val = 2; set(findobj('Tag','szMode'),'value',val);
                case 'F'
                    val = 3; set(findobj('Tag','szMode'),'value',val);
            end
            set(findobj('Tag','nMode_cs'),'String',nMode_cs);
        end

        % Button pushed function: Run
        function Run_Callback(app, event)
            % --- Executes on button press in Run.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Run (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            clc; tic; diary diary.log;
            disp('Initializing...');
            szCheck = questdlg('To check roots manually?', 'Choice', 'Yes', 'No', 'Yes'); pause(0.1);
            %% %参数设定【Parameters Setting】
            global szFun szMethod szBC szMode nMode_cs szChkCho
            szChkCho = 'Yes';
            szMethod = 'Mixed';
            %------------------------------------------------------------------------------
            rowem = str2double(get(findobj('tag','rowem'),'string'));
            Eem0 = str2double(get(findobj('tag','Eem0'),'string'));
            ytaem = str2double(get(findobj('tag','ytaem'),'string'));
            sigmaem = str2double(get(findobj('tag','sigmaem'),'string'));
            rowvm = str2double(get(findobj('tag','rowvm'),'string'));
            Evm0 = str2double(get(findobj('tag','Evm0'),'string'));
            ytavm = str2double(get(findobj('tag','ytavm'),'string'));
            sigmavm = str2double(get(findobj('tag','sigmavm'),'string'));
            row1 = str2double(get(findobj('tag','row1'),'string'));
            c1 = str2double(get(findobj('tag','c1'),'string'));
            row2 = str2double(get(findobj('tag','row2'),'string'));
            c2 = str2double(get(findobj('tag','c2'),'string'));
            
            dem = str2double(get(findobj('tag','dem'),'string'));
            dvm = str2double(get(findobj('tag','dvm'),'string'));
            a_inner = str2double(get(findobj('tag','a_inner'),'string'));
            b_middle = str2double(get(findobj('tag','b_middle'),'string'));
            c_outer = str2double(get(findobj('tag','c_outer'),'string'));
            
            h = findobj('tag','szFun'); str = get(h,'string'); val = get(h,'value'); szFun = str{val};
            h = findobj('tag','szBC'); str = get(h,'string'); val = get(h,'value'); szBC = str{val};
            nMode_cs = str2double(get(findobj('tag','ncs'),'string'));
            kur = str2double(get(findobj('tag','kur'),'string'));
            h = findobj('tag','szMode'); str = get(h,'string'); val = get(h,'value'); szMode = str{val};
            
            fa = str2double(get(findobj('tag','fa'),'string'));
            df = str2double(get(findobj('tag','df'),'string'));
            fb = str2double(get(findobj('tag','fb'),'string'));
            cpa = str2double(get(findobj('tag','cpa'),'string'));
            dcp = str2double(get(findobj('tag','dcp'),'string'));
            cpb = str2double(get(findobj('tag','cpb'),'string'));
            kia = str2double(get(findobj('tag','kia'),'string'));
            dki = str2double(get(findobj('tag','dki'),'string'));
            kib = str2double(get(findobj('tag','kib'),'string'));
            err = str2double(get(findobj('tag','err'),'string'));
            %%
            % %参数设定
            F = fa:df:fb;
            Evm = Evm0*(1-1i*ytavm);
            lamdavm = Evm*sigmavm/((1+sigmavm)*(1-2*sigmavm)); miuvm = Evm/(2*(1+sigmavm));
            clvm = sqrt((lamdavm+2*miuvm)/rowvm); ctvm = sqrt(miuvm/rowvm);
            crvm = RayleighWave(clvm, ctvm);
            Eem = Eem0*(1-1i*ytaem);
            lamdaem = Eem*sigmaem/((1+sigmaem)*(1-2*sigmaem)); miuem = Eem/(2*(1+sigmaem));
            clem = sqrt((lamdaem+2*miuem)/rowem); ctem = sqrt(miuem/rowem);
            crem = RayleighWave(clem, ctem); %瑞利波速
            %参数保存【Parameters Save】
            save('data.mat', 'row1', 'c1', 'row2', 'c2');
            save('data.mat', 'rowvm', 'Evm0', 'ytavm', 'sigmavm', 'rowem', 'Eem0', 'ytaem', 'sigmaem', '-append');
            save('data.mat', 'lamdavm', 'miuvm', 'clvm', 'ctvm', 'lamdaem', 'miuem', 'clem', 'ctem', '-append');
            save('data.mat', 'dem', 'dvm', 'a_inner', 'b_middle', 'c_outer', '-append');
            save('data.mat', 'F', 'szFun', 'szBC', 'szMode', '-append');
            %% %频散方程求根【Roots Find of Dispersion Equation】
            %材料参数【Material Parameters】
            MPM = [row1, c1, rowvm, lamdavm, miuvm, dvm, rowem, lamdaem, miuem, dem, row2, c2,...
                a_inner, b_middle, c_outer];
            %奇异相速度【Singular Phase Velocities】
            SVM = [clem, ctem, crem, clvm, ctvm, crvm];
            %搜索参数【Search Parameters】
            SPM = [cpa, dcp, cpb, kia, dki, kib];
            save('data.mat', 'MPM', 'SVM', 'SPM', '-append');
            %%
            nL = 1E1;
            cp_ps0 = zeros(length(F), nL); cp_ps0(:,:) = NaN; ki_ps0 = cp_ps0;
            cp_ps1 = cp_ps0; ki_ps1 = cp_ps0; cp_ps2 = cp_ps0;
            ki_ps2 = cp_ps0; cp_ps3 = cp_ps0; ki_ps3 = cp_ps0;
            MSW = zeros(length(F), 6); MSW(:, :) = NaN;
            n = 1;
            disp(['toc: ', num2str(toc), 's']);
            disp('*******************************************************');
            for f = F
                diary on;
                disp(['f = ', num2str(f), 'Hz']);
                w = 2*pi*f;
                %% %全局搜索【Global Search】
                [cp1, ki1]= GlobalDomainSearch(f, MPM, SPM, kur, err);
                %% %局部搜索【Local Search】
                [cp2, ki2, SW]= LocalDomainSearch(f, MPM, SPM, SVM, kur, err);
                MSW(n, 1:length(SW)) = w./real(SW)+1i*imag(SW); %奇异波数：实部为波速，虚部为衰减系数
                %【Singular Wavenumbers(SW)：real->velocity, imag.->attenuation】
                %% %根值合并【Roots Merge】
                [cp_0, ki_0, cp_1, ki_1, cp_2, ki_2] = RootsMerge(f, SW, cp1, ki1, cp2, ki2);
                cp_ps0(n, 1:length(cp_0)) = cp_0; ki_ps0(n, 1:1:length(ki_0)) = ki_0;
                cp_ps1(n, 1:1:length(cp_1)) = cp_1; ki_ps1(n, 1:1:length(ki_1)) = ki_1;
                cp_ps2(n, 1:1:length(cp_2)) = cp_2; ki_ps2(n, 1:1:length(ki_2)) = ki_2;
                save('data.mat', 'cp_ps0', 'ki_ps0', 'cp_ps1', 'ki_ps1', 'cp_ps2', 'ki_ps2', 'MSW', '-append');
                %% %根值校验【Roots Check】
                if strcmp(szCheck, 'Yes') && ~strcmp(szChkCho, 'Never')
                    [cp_3, ki_3] = RootsCheck(f, MPM, cp_0, ki_0);
                    cp_ps3(n,1:1:length(cp_3)) = cp_3; ki_ps3(n, 1:1:length(ki_3)) = ki_3;
                    save('data.mat', 'cp_ps3', 'ki_ps3', '-append');
                end
                %% %后处理【Post Process】
                disp('*******************************************************');
                diary off; n = n+1;
            end
            disp('done!');
        end

        % Button pushed function: Save
        function Save_Callback(app, event)
            % --- Executes on button press in Save.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Save (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            row1 = str2double(get(findobj('Tag','row1'),'String')); c1 = str2double(get(findobj('Tag','c1'),'String'));
            rowem = str2double(get(findobj('Tag','rowem'),'String')); Eem0 = str2double(get(findobj('Tag','Eem0'),'String'));
            ytaem = str2double(get(findobj('Tag','ytaem'),'String')); sigmaem = str2double(get(findobj('Tag','sigmaem'),'String'));
            rowvm = str2double(get(findobj('Tag','rowvm'),'String')); Evm0 = str2double(get(findobj('Tag','Evm0'),'String'));
            ytavm = str2double(get(findobj('Tag','ytavm'),'String')); sigmavm = str2double(get(findobj('Tag','sigmavm'),'String'));
            row2 = str2double(get(findobj('Tag','row2'),'String')); c2 = str2double(get(findobj('Tag','c2'),'String'));
            
            dem = str2double(get(findobj('Tag','dem'),'String')); dvm = str2double(get(findobj('Tag','dvm'),'String'));
            a_inner = str2double(get(findobj('Tag','a_inner'),'String')); b_middle = str2double(get(findobj('Tag','b_middle'),'String'));
            c_outer = str2double(get(findobj('Tag','c_outer'),'String'));
            fa = str2double(get(findobj('Tag','fa'),'String')); df = str2double(get(findobj('Tag','df'),'String'));
            fb = str2double(get(findobj('Tag','fb'),'String')); cpa = str2double(get(findobj('Tag','cpa'),'String'));
            dcp = str2double(get(findobj('Tag','dcp'),'String')); cpb = str2double(get(findobj('Tag','cpb'),'String'));
            kia = str2double(get(findobj('Tag','kia'),'String')); dki = str2double(get(findobj('Tag','dki'),'String'));
            kib = str2double(get(findobj('Tag','kib'),'String'));
            
            str = get(findobj('Tag','szFun'),'String'); val = get(findobj('Tag','szFun'),'value'); szFun = str{val};
            str = get(findobj('Tag','szBC'),'String'); val = get(findobj('Tag','szBC'),'value'); szBC = str{val};
            str = get(findobj('Tag','szMode'),'String'); val = get(findobj('Tag','szMode'),'value'); szMode = str{val};
            nMode_cs = str2double(get(findobj('Tag','nMode_cs'),'String'));
            kur = str2double(get(findobj('Tag','kur'),'String')); err = str2double(get(findobj('Tag','err'),'String'));
            
            fid= fopen('Initializer.mat');
            if fid==-1, readme = 'Parameters File'; save('Initializer.mat','readme'); end
            readme = 'Parameters File'; save('Initializer.mat','readme','-append');
            save('Initializer.mat','row1','c1','rowem','Eem0','ytaem','sigmaem','rowvm','Evm0','ytavm','sigmavm','row2','c2','-append');
            save('Initializer.mat','dem','dvm','a_inner','b_middle','c_outer','-append');
            save('Initializer.mat','fa','df','fb','cpa','dcp','cpb','kia','dki','kib','-append');
            save('Initializer.mat','szFun','szBC','szMode','nMode_cs','kur','err','-append');
            if fid ~= -1; fclose(fid); end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'main';
            app.figure1.Tag = 'figure1';
            app.uipanel8 = uipanel(app.figure1);
            app.uipanel8.Tag = 'uipanel8';
            app.uipanel1 = uipanel(app.uipanel8);
            app.uipanel1.Title = 'Material Parameters';
            app.uipanel1.Tag = 'uipanel1';
            app.c2 = uieditfield(app.uipanel1, 'text');
            app.c2.Tag = 'c2';
            app.c2.Value = '1.5E3';
            app.row2 = uieditfield(app.uipanel1, 'text');
            app.row2.Tag = 'row2';
            app.row2.Value = '1E3';
            app.text16 = uilabel(app.uipanel1);
            app.text16.Tag = 'text16';
            app.text16.Text = 'c2';
            app.text15 = uilabel(app.uipanel1);
            app.text15.Tag = 'text15';
            app.text15.Text = 'row2';
            app.text14 = uilabel(app.uipanel1);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Fluid 2';
            app.c1 = uieditfield(app.uipanel1, 'text');
            app.c1.Tag = 'c1';
            app.c1.Value = '1.5E3';
            app.row1 = uieditfield(app.uipanel1, 'text');
            app.row1.Tag = 'row1';
            app.row1.Value = '1E3';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'c1';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'row1';
            app.text1 = uilabel(app.uipanel1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Fluid 1';
            app.sigmavm = uieditfield(app.uipanel1, 'text');
            app.sigmavm.Tag = 'sigmavm';
            app.sigmavm.Value = '0.49';
            app.ytavm = uieditfield(app.uipanel1, 'text');
            app.ytavm.Tag = 'ytavm';
            app.ytavm.Value = '0.23';
            app.Evm0 = uieditfield(app.uipanel1, 'text');
            app.Evm0.Tag = 'Evm0';
            app.Evm0.Value = '1.4E8';
            app.rowvm = uieditfield(app.uipanel1, 'text');
            app.rowvm.Tag = 'rowvm';
            app.rowvm.Value = '1.1E3';
            app.text13 = uilabel(app.uipanel1);
            app.text13.Tag = 'text13';
            app.text13.Text = 'sigmavm';
            app.text12 = uilabel(app.uipanel1);
            app.text12.Tag = 'text12';
            app.text12.Text = 'ytavm';
            app.text11 = uilabel(app.uipanel1);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Evm0';
            app.text10 = uilabel(app.uipanel1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'rowvm';
            app.text9 = uilabel(app.uipanel1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Material 2: Viscolastic';
            app.sigmaem = uieditfield(app.uipanel1, 'text');
            app.sigmaem.Tag = 'sigmaem';
            app.sigmaem.Value = '0.28';
            app.ytaem = uieditfield(app.uipanel1, 'text');
            app.ytaem.Tag = 'ytaem';
            app.ytaem.Value = '0';
            app.Eem0 = uieditfield(app.uipanel1, 'text');
            app.Eem0.Tag = 'Eem0';
            app.Eem0.Value = '21.6E10';
            app.rowem = uieditfield(app.uipanel1, 'text');
            app.rowem.Tag = 'rowem';
            app.rowem.Value = '7.84E3';
            app.text8 = uilabel(app.uipanel1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'sigmaem';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'rowem';
            app.text7 = uilabel(app.uipanel1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'ytaem';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Eem0';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Material 1: Elastic';
            app.uipanel2 = uipanel(app.uipanel8);
            app.uipanel2.Title = 'Computation Parameters';
            app.uipanel2.Tag = 'uipanel2';
            app.text65 = uilabel(app.uipanel2);
            app.text65.Tag = 'text65';
            app.text65.Text = 'kur';
            app.kur = uieditfield(app.uipanel2, 'text');
            app.kur.Tag = 'kur';
            app.kur.Value = '1E-3';
            app.text29 = uilabel(app.uipanel2);
            app.text29.Tag = 'text29';
            app.text29.Text = 'Control Parameters';
            app.err = uieditfield(app.uipanel2, 'text');
            app.err.Tag = 'err';
            app.err.Value = '1E-16';
            app.text30 = uilabel(app.uipanel2);
            app.text30.Tag = 'text30';
            app.text30.Text = 'err';
            app.kib = uieditfield(app.uipanel2, 'text');
            app.kib.Tag = 'kib';
            app.kib.Value = '100';
            app.dki = uieditfield(app.uipanel2, 'text');
            app.dki.Tag = 'dki';
            app.dki.Value = '10';
            app.kia = uieditfield(app.uipanel2, 'text');
            app.kia.Tag = 'kia';
            app.kia.Value = '-1';
            app.text28 = uilabel(app.uipanel2);
            app.text28.Tag = 'text28';
            app.text28.Text = 'kib';
            app.text27 = uilabel(app.uipanel2);
            app.text27.Tag = 'text27';
            app.text27.Text = 'dki';
            app.text26 = uilabel(app.uipanel2);
            app.text26.Tag = 'text26';
            app.text26.Text = 'kia';
            app.text25 = uilabel(app.uipanel2);
            app.text25.Tag = 'text25';
            app.text25.Text = 'Attenuation Coefficient';
            app.cpb = uieditfield(app.uipanel2, 'text');
            app.cpb.Tag = 'cpb';
            app.cpb.Value = '2E4';
            app.dcp = uieditfield(app.uipanel2, 'text');
            app.dcp.Tag = 'dcp';
            app.dcp.Value = '1';
            app.cpa = uieditfield(app.uipanel2, 'text');
            app.cpa.Tag = 'cpa';
            app.cpa.Value = '1E3';
            app.text24 = uilabel(app.uipanel2);
            app.text24.Tag = 'text24';
            app.text24.Text = 'cpb';
            app.text23 = uilabel(app.uipanel2);
            app.text23.Tag = 'text23';
            app.text23.Text = 'dcp';
            app.text22 = uilabel(app.uipanel2);
            app.text22.Tag = 'text22';
            app.text22.Text = 'cpa';
            app.text21 = uilabel(app.uipanel2);
            app.text21.Tag = 'text21';
            app.text21.Text = 'Phase Velocity';
            app.fb = uieditfield(app.uipanel2, 'text');
            app.fb.Tag = 'fb';
            app.fb.Value = '100E4';
            app.df = uieditfield(app.uipanel2, 'text');
            app.df.Tag = 'df';
            app.df.Value = '1E4';
            app.fa = uieditfield(app.uipanel2, 'text');
            app.fa.Tag = 'fa';
            app.fa.Value = '1E4';
            app.text20 = uilabel(app.uipanel2);
            app.text20.Tag = 'text20';
            app.text20.Text = 'fb';
            app.text19 = uilabel(app.uipanel2);
            app.text19.Tag = 'text19';
            app.text19.Text = 'df';
            app.text18 = uilabel(app.uipanel2);
            app.text18.Tag = 'text18';
            app.text18.Text = 'fa';
            app.text17 = uilabel(app.uipanel2);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Frequency Range';
            app.Initialize = uibutton(app.uipanel8, 'push');
            app.Initialize.ButtonPushedFcn = createCallbackFcn(app, @Initialize_Callback, true);
            app.Initialize.Tag = 'Initialize';
            app.Initialize.Text = 'Initialize';
            app.Save = uibutton(app.uipanel8, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @Save_Callback, true);
            app.Save.Tag = 'Save';
            app.Save.Text = 'Save';
            app.Run = uibutton(app.uipanel8, 'push');
            app.Run.ButtonPushedFcn = createCallbackFcn(app, @Run_Callback, true);
            app.Run.Tag = 'Run';
            app.Run.Text = 'Run';
            app.uipanel3 = uipanel(app.uipanel8);
            app.uipanel3.Title = 'Model Size';
            app.uipanel3.Tag = 'uipanel3';
            app.c_outer = uieditfield(app.uipanel3, 'text');
            app.c_outer.Tag = 'c_outer';
            app.c_outer.Value = '0.03';
            app.text42 = uilabel(app.uipanel3);
            app.text42.Tag = 'text42';
            app.text42.Text = 'c';
            app.b_middle = uieditfield(app.uipanel3, 'text');
            app.b_middle.Tag = 'b_middle';
            app.b_middle.Value = '0.02';
            app.a_inner = uieditfield(app.uipanel3, 'text');
            app.a_inner.Tag = 'a_inner';
            app.a_inner.Value = '0.01';
            app.text41 = uilabel(app.uipanel3);
            app.text41.Tag = 'text41';
            app.text41.Text = 'b';
            app.text40 = uilabel(app.uipanel3);
            app.text40.Tag = 'text40';
            app.text40.Text = 'a';
            app.text39 = uilabel(app.uipanel3);
            app.text39.Tag = 'text39';
            app.text39.Text = 'Cylindrical shells';
            app.dvm = uieditfield(app.uipanel3, 'text');
            app.dvm.Tag = 'dvm';
            app.dvm.Value = '0.01';
            app.dem = uieditfield(app.uipanel3, 'text');
            app.dem.Tag = 'dem';
            app.dem.Value = '0.01';
            app.text38 = uilabel(app.uipanel3);
            app.text38.Tag = 'text38';
            app.text38.Text = 'dvm';
            app.text37 = uilabel(app.uipanel3);
            app.text37.Tag = 'text37';
            app.text37.Text = 'dem';
            app.text36 = uilabel(app.uipanel3);
            app.text36.Tag = 'text36';
            app.text36.Text = 'Plates';
            app.uipanel4 = uipanel(app.uipanel8);
            app.uipanel4.Title = 'Problem Selection';
            app.uipanel4.Tag = 'uipanel4';
            app.text31 = uilabel(app.uipanel4);
            app.text31.Tag = 'text31';
            app.text31.Text = 'Model Selection';
            app.nMode_cs = uieditfield(app.uipanel4, 'text');
            app.nMode_cs.Tag = 'nMode_cs';
            app.nMode_cs.Value = '0';
            app.text35 = uilabel(app.uipanel4);
            app.text35.Tag = 'text35';
            app.text35.Text = 'ncs';
            app.szMode = uidropdown(app.uipanel4);
            app.szMode.Items = {'S/L', 'A/T', 'F'};
            app.szMode.Tag = 'szMode';
            app.szMode.Value = 'S/L';
            app.text33 = uilabel(app.uipanel4);
            app.text33.Tag = 'text33';
            app.text33.Text = 'Mode Selection';
            app.text34 = uilabel(app.uipanel4);
            app.text34.Tag = 'text34';
            app.text34.Text = 'Mode';
            app.szBC = uidropdown(app.uipanel4);
            app.szBC.Items = {'Va-So-Va', 'Va-So-Fl', 'Fl-So-Fl', 'Va-So-So-Va', 'Va-So-So-Fl', 'Fl-So-So-Fl'};
            app.szBC.Tag = 'szBC';
            app.szBC.Value = 'Va-So-Va';
            app.text32 = uilabel(app.uipanel4);
            app.text32.Tag = 'text32';
            app.text32.Text = 'Boundary Conditions';
            app.szFun = uidropdown(app.uipanel4);
            app.szFun.Items = {'PlanarPlate', 'CylindricalShell'};
            app.szFun.Tag = 'szFun';
            app.szFun.Value = 'PlanarPlate';
            app.text69 = uilabel(app.uipanel8);
            app.text69.Tag = 'text69';
            app.text69.Text = '【Jiayuan Gong (龚家元)，jygong33@qq.com】';
            app.text68 = uilabel(app.uipanel8);
            app.text68.Tag = 'text68';
            app.text68.Text = 'LPS (Local-Peaks Search) Method';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIMain_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)GUIMain_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end